classdef Data_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ListBoxLabel           matlab.ui.control.Label
        ListBox                matlab.ui.control.ListBox
        ListBox2Label          matlab.ui.control.Label
        ListBox2               matlab.ui.control.ListBox
        TextAreaLabel          matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes2_2              matlab.ui.control.UIAxes
        PlotsButtonGroup       matlab.ui.container.ButtonGroup
        PSTHButton             matlab.ui.control.ToggleButton
        Vector_StrengthButton  matlab.ui.control.ToggleButton
        RateStimuliButton      matlab.ui.control.ToggleButton
        FanofactorButton       matlab.ui.control.ToggleButton
        VarianceButton         matlab.ui.control.ToggleButton
        SpikesClickButton      matlab.ui.control.ToggleButton
        RunSelection           matlab.ui.control.StateButton
        LogTextAreaLabel       matlab.ui.control.Label
        LogTextArea            matlab.ui.control.TextArea
        MonotonicKnobLabel     matlab.ui.control.Label
        MonotonicKnob          matlab.ui.control.DiscreteKnob
        SyncNSyncSwitchLabel   matlab.ui.control.Label
        SyncNSyncSwitch        matlab.ui.control.ToggleSwitch
        StimuliSetSwitchLabel  matlab.ui.control.Label
        StimuliSetSwitch       matlab.ui.control.ToggleSwitch
    end

    
    properties (Access = private)
        %Load parameters
        ICI_list1 = [2 2.5 3 5 7.5 10 12.5 15 20 25 30 35 40 45 50 55 60 65 70 75]; %ms, ICI
        ICI_list3 = [250 125 83.3333 62.5 50 41.6667 35.7143 31.25 27.7778 25 22.7273 20.8333];
        stim_duration = 0.5; % Description
        animal = '';
        indexes = [];  %all conditions
        indexes1 = []; % Sync/NSync
        indexes2 = []; %Monotonic
        indexes3 = []; %Stim set
        %Plots
        vector = [];
        ICI_list = [];
        x_axis_PSTH = [];
        all_rate = {};
        all_std_rate_stim = [];
        all_mean_rate_stim = [];
        Fanofactor = [];
        meanOFstd = [];
        var_isi = [];
        spike_time_IPI ={};
        spikes_per_click ={};
        rate_stim = {};
        std_stim = {};
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Initialise Listboxes
            
            Animals = {'m2p', 'm32q', 'm36n', 'm41o'};
            app.ListBox.Items = Animals;
            app.ListBox2.Items = {};
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.animal = app.ListBox.Value;
            load([ app.animal '_list3'])
            
            app.RunSelection.Value = 0;
            app.RunSelection.Text = 'RUNNING...';
            app.RunSelection.FontColor = [1.00,0.27,0.00];
            app.ListBox2.Items = UnitInfo.List;


            app.RunSelection.Value = 0;
            app.RunSelection.Text = 'Standby';
            app.RunSelection.FontColor = [0.00,0.00,0.00];
        end

        % Value changed function: ListBox2
        function ListBox2ValueChanged(app, event)
            app.animal = app.ListBox.Value;
            hold(app.UIAxes2_2,'off')
            hold(app.UIAxes,'off');
            load([ app.animal '_list3'])
            
            neuron = app.ListBox2.Value;
            for ind = 1:length(UnitInfo.List)
                if strcmp(UnitInfo.List{ind},neuron) ==1
                    n= ind;
                end
            end
            %                         directory = ['U:\Neural and Behavioural Data' '\marmoset\' app.animal];
            %             directory = ['C:\Users\John\Dropbox\Bendorlab\Code' '\marmoset\' app.animal];
            directory = ['C:\Users\John.Lee\OneDrive\Bendorlab\Marmoset\' app.animal];
            
            spiketable = load([directory filesep neuron]);


            % load info on recording
            if UnitInfo.Info(n).Stimuli_Nb == 12
                app.ICI_list = app.ICI_list3;
            else % UnitInfo.Info(2,n) == 20
                app.ICI_list = app.ICI_list1;
            end
            PREstim_duration = UnitInfo.Info(n).Pre_stim_Duration/1000; %seconds
            TrialLength = UnitInfo.Info(n).Pre_stim_Duration + UnitInfo.Info(n).Post_stim_Duration +500;
            
            app.vector = zeros(1,length(app.ICI_list));
            raster.stim=[];  raster.rep=[];  raster.spikes=[];
            spikes_pooled_for_VS = [];
            all_mean_rate_spont = [];
            all_std_rate_spont = [];
            app.all_mean_rate_stim = [];
            app.all_std_rate_stim = [];
            app.Fanofactor = [];
            app.meanOFstd = [];
            app.var_isi = [];
            med = [];
            for f = 1:length(app.ICI_list)
                freq = (1e3)./app.ICI_list(f);
                app.spike_time_IPI{f} = [];
                
                indstim = find(spiketable(:,1)==f);
                nbrep = spiketable(find(spiketable(:,1)==f),2);
                nreps = max(nbrep.');
                spikes_pooled = [];
                rate_total = [];
                if ~isempty(nbrep)
                    for r = unique(nbrep.')
                        spikes1 = []; %channel 1
                        spikes2 = []; %channel 2
                        for i = indstim.'
                            if spiketable(i,2) == r && spiketable(i,3)== 1 && spiketable(i,4) > 0 % channel 1
                                spikes1 = [spikes1 (spiketable(i,4)*1e-6 - PREstim_duration)]; % spikes rounded up to 0.1ms cad 100microseconds. converted to seconds
                            end
                            if spiketable(i,2) == r && spiketable(i,3)== 2 && spiketable(i,4) > 0 % channel 1
                                spikes2 = [spikes2 (spiketable(i,4)*1e-6 - PREstim_duration)];
                            end
                        end
                        if length(spikes1) > length(spikes2)
                            spikes =  spikes1;
                        else
                            spikes = spikes2;
                        end
                        spikes_pooled = [spikes_pooled spikes];
                        raster.stim=[raster.stim f*ones(size(spikes))];
                        raster.rep=[raster.rep r*ones(size(spikes))];
                        raster.spikes=[raster.spikes spikes];
                        
                        % rate
                        rate = zeros(1,TrialLength);
                        spikes4rate = spikes(find(spikes<PREstim_duration+0.5)) + PREstim_duration;
                        for st = spikes4rate
                            rate(1,ceil(st*1e3)) = rate(1,ceil(st*1e3))+1;
                        end
                        rate_total = [rate_total ; rate*1000];
                    end
                    
                    
                    %Calculate Fanofactor
                    
                    SpikeCount = sum(rate_total(:,PREstim_duration*1000+1:PREstim_duration*1000+600),2)/1000.;
                    app.Fanofactor(f) = std(SpikeCount)^2/mean(SpikeCount);


                    %average rate + gaussian kernel
                    rate_av = mean(rate_total,1);
                    xs = 1:TrialLength;
                    h = 15; %kernal bandwidth. determines the shape of the function
                    for i = 1:TrialLength
                        ys(i)=gaussian_kern_reg(xs(i),xs,rate_av,h);
                    end
                    app.all_rate{f} = ys;
                    all_mean_rate_spont(f) = mean2(rate_total(:,1:PREstim_duration*1000));
                    all_std_rate_spont(f) = std2(rate_total(:,1:PREstim_duration*1000))/sqrt(PREstim_duration*1000*nreps);
                    app.all_mean_rate_stim(f) = mean2(rate_total(:,PREstim_duration*1000+1:PREstim_duration*1000+600))-all_mean_rate_spont(f);
                    app.all_std_rate_stim(f) = std2(rate_total(:,PREstim_duration*1000+1:PREstim_duration*1000+600))/sqrt(600*nreps);
                    app.meanOFstd(f)= mean(std(rate_total(:,PREstim_duration*1000+1:PREstim_duration*1000+600),0,2)/sqrt(600*nreps));
                    spikes_pooled_for_VS=spikes_pooled(find(spikes_pooled>0.05 & spikes_pooled<=(app.stim_duration+0.05)));
                    app.rate_stim{f} = rate_av(1,PREstim_duration*1000+1:PREstim_duration*1000+600)-all_mean_rate_spont(f);
                    app.std_stim{f} = std(rate_total(:,PREstim_duration*1000+1:PREstim_duration*1000+600))/sqrt(600*nreps);
                    %Interspike interval
                    spikes1 = spikes_pooled_for_VS;
                    spikes2 = [0 spikes1(1:end-1)];
                    isi = spikes1-spikes2;
                    app.var_isi = [app.var_isi std(isi)];
                    
                    
                    %
                    
                    %for calculating vector strength, subtract the first 50 ms and
                    %include 50 ms post stimulus
                    
                    
                    if ~isempty(spikes_pooled_for_VS)
                        total_spikes = length(spikes_pooled_for_VS);
                        x = 0;
                        y = 0;
                        if total_spikes > 0
                            x = sum(cos(2*pi*(spikes_pooled_for_VS*freq)));
                            y = sum(sin(2*pi*(spikes_pooled_for_VS*freq)));
                            app.vector(f) = sqrt(x^2+y^2)/total_spikes;
                        elseif total_spikes == 0
                            app.vector(f) = 0;
                        end
                        rayleigh(f) = 2*total_spikes*app.vector(f)^2;
                    else
                        app.vector(f)=0;
                        rayleigh(f)=0;
                    end
                    
                    if rayleigh(f)<13.8
                        app.vector(f)=0;
                    end
                    
                    app.spike_time_IPI{f} = [app.spike_time_IPI{f} mod(spikes_pooled_for_VS,app.ICI_list(f)*1e-3)];
                    %replace if studying VS % 2*pi*[mod(spikes_pooled_for_vector_strength,ICI_list(f)*1e-3)]*freq2];
                    med = [med median(app.spike_time_IPI{f})];
                    
                end
            end
            medtime = round(median(med(1:5))*1e3);
            for f = 1:length(app.ICI_list)
                p = floor(500/app.ICI_list(f));
                
                for q = 1:p
                    clicktime = round((q-1)*app.ICI_list(f))+medtime;
                    app.spikes_per_click{f}.brut(1,q) = mean(app.rate_stim{f}(1,clicktime-15:clicktime+15));
                    app.spikes_per_click{f}.xaxis(1,q) = clicktime+500;
                end
                
            end
            
            
            %% spikes per click data
            
            cmap = colormap(jet(length(app.ICI_list)));
            
            for f = 2:2:length(app.ICI_list)
                plot(app.UIAxes2,app.spikes_per_click{f}.xaxis,app.spikes_per_click{f}.brut,'color',cmap(f,:),'linewidth',1.7)%,'DisplayName',[num2str(ceil(1000/app.ICI_list(n))) 'Hz'])
                hold(app.UIAxes2,'on');
                
            end
            hold(app.UIAxes2,'off')

            
            %% Rate PSTH
            app.x_axis_PSTH = linspace(-PREstim_duration, app.stim_duration + UnitInfo.Info(n).Post_stim_Duration*1e-3 , TrialLength);
            
            
            %             app.UIAxes2_2(cmap = colormap());
            
            lgd = {};
            p = 1;
            for n = 2:2:length(app.ICI_list)
                plot(app.UIAxes2_2,app.x_axis_PSTH,app.all_rate{n},'color',cmap(n,:),'linewidth',1.7)%,'DisplayName',[num2str(ceil(1000/app.ICI_list(n))) 'Hz'])
                hold(app.UIAxes2_2,'on');
                lgd{p} = [num2str(round(1000/app.ICI_list(n))) 'Hz'];
                p = p+1;
            end
            app.UIAxes2_2.XLabel.String = 'time (s)';
            app.UIAxes2_2.YLabel.String = 'rate (spikes/s)';
            app.UIAxes2_2.Title.String = 'Instantaneous discharge rate';
            app.UIAxes2_2.XLim = [-0.2 0.8];
            legend(app.UIAxes2_2,lgd)
            
            %% Raster Plot
            app.UIAxes.XLabel.String = 'time (s)';
            app.UIAxes.YLabel.String = 'IPI (ms)';
            
            ytext = [];
            ytext_position = [];
            for s = 1:length(app.ICI_list)
                ytext_position = [ytext_position;s*nreps-nreps/2];
                ytext = char(ytext,num2str(app.ICI_list(s)));
            end
            ytext(1,:) = [];
            area(app.UIAxes, [0 app.stim_duration app.stim_duration 1],[(length(app.ICI_list)+1)*nreps+1 (length(app.ICI_list)+1)*nreps+1 0 0],'LineStyle','none','FaceColor',[.85 .85 1]);
            hold(app.UIAxes,'on');
            scatter(app.UIAxes, raster.spikes,nreps*(raster.stim-1)+raster.rep,5,'k','filled','o')
            app.UIAxes.XLim = [-0.5 1];
            app.UIAxes.YLim = [0 (length(app.ICI_list)+1)*nreps+1];
            set(app.UIAxes,'yTick',ytext_position);
            set(app.UIAxes,'yTickLabel',ytext);
            
            if length(app.ICI_list) == 12
                app.ICI_list = app.ICI_list(2:end);
                app.all_mean_rate_stim = app.all_mean_rate_stim(2:end);
                app.all_std_rate_stim = app.all_std_rate_stim(2:end);
                app.vector = app.vector(2:end);
                app.Fanofactor = app.Fanofactor(2:end);
                app.meanOFstd = app.meanOFstd(2:end);
                app.var_isi = app.var_isi(2:end);
            else
                app.ICI_list = app.ICI_list(4:end);
                app.all_mean_rate_stim = app.all_mean_rate_stim(4:end);
                app.all_std_rate_stim = app.all_std_rate_stim(4:end);
                app.vector = app.vector(4:end);
                app.Fanofactor = app.Fanofactor(4:end);
                app.meanOFstd = app.meanOFstd(4:end);
                app.var_isi = app.var_isi(4:end);
            end


        end

        % Value changed function: SyncNSyncSwitch
        function SyncNSyncSwitchValueChanged(app, event)
            state = app.SyncNSyncSwitch.Value;
            load([ app.animal '_list3'])
            if strcmp(state,'Sync') ==1
                app.indexes1 = find( [UnitInfo.Info.Sync] == 1);
            else
                app.indexes1 = find( [UnitInfo.Info.Sync] == 0 & [UnitInfo.Info.Significant_rate] == 1 );
            end
            app.ListBox2.Items = {};
            Trans = {};
            
            if isempty(app.indexes2) && isempty(app.indexes3)
                app.indexes = app.indexes1;
            elseif isempty(app.indexes2)
                app.indexes = intersect(app.indexes1, app.indexes3);
            elseif isempty(app.indexes3)
                app.indexes = intersect(app.indexes1, app.indexes2);
            else
                app.indexes = intersect(app.indexes1, intersect(app.indexes2, app.indexes3));
            end
            
            for ind = 1:length(app.indexes)
                Trans{ind} = UnitInfo.List{app.indexes(ind)};
            end
            app.ListBox2.Items = Trans;
            
        end

        % Value changed function: MonotonicKnob
        function MonotonicKnobValueChanged(app, event)
            state = app.MonotonicKnob.Value;
            load([ app.animal '_list3'])
            if strcmp(state,'Positive')
                app.indexes2 = find([UnitInfo.Info.Positive] == -1); % otherway round
            elseif strcmp(state,'Negative')
                app.indexes2 = find([UnitInfo.Info.Positive] == 1);
            else
                app.indexes2 = find([UnitInfo.Info.Positive] == 0);
            end
            if isempty(app.indexes1) && isempty(app.indexes3)
                app.indexes = app.indexes2;
            elseif isempty(app.indexes1)
                app.indexes = intersect(app.indexes2, app.indexes3);
            elseif isempty(app.indexes3)
                app.indexes = intersect(app.indexes1, app.indexes2);
            else
                app.indexes = intersect(app.indexes1, intersect(app.indexes2, app.indexes3));
            end
            
            for ind = 1:length(app.indexes)
                Trans{ind} = UnitInfo.List{app.indexes(ind)};
            end
            app.ListBox2.Items = Trans;
        end

        % Selection changed function: PlotsButtonGroup
        function PlotsButtonGroupSelectionChanged(app, event)
            selected = app.PlotsButtonGroup.SelectedObject;
            
            
            if strcmp(selected.Text,'PSTH')
                
                %% Rate PSTH
                cmap = colormap(jet(length(app.ICI_list)));
                %             app.UIAxes2_2(cmap = colormap());
                
                lgd = {};
                p = 1;
                for n = 2:2:length(app.ICI_list)
                    plot(app.UIAxes2_2,app.x_axis_PSTH,app.all_rate{n},'color',cmap(n,:),'linewidth',2.0)%,'DisplayName',[num2str(ceil(1000/app.ICI_list(n))) 'Hz'])
                    hold(app.UIAxes2_2,'on');
                    lgd{p} = [num2str(ceil(1000/app.ICI_list(n))) 'Hz'];
                    p = p+1;
                end
                app.UIAxes2_2.XLabel.String = 'time (s)';
                app.UIAxes2_2.YLabel.String = 'rate (spikes/s)';
                app.UIAxes2_2.Title.String = 'Instantaneous discharge rate';
                app.UIAxes2_2.XLim = [-0.2 0.8];
                legend(app.UIAxes2_2,lgd)
                
                %             elseif strcmp(selected.Text,'PeriHistogram')
                %                 %         edges = 0: pi/64: 2*pi;
                %
                %                 fig = uifigure;
                %                 edges = 0:0.001:0.125;
                %                 for i = length(app.ICI_list)
                %                     subplot(12,1,i)
                %                     histogram(app.UIAxes,app.spike_time_IPI{i},edges)
                %                     hold(app.UIAxes, 'on');
                %                 end
                %                  hold(app.UIAxes, 'off');
                
                
            elseif strcmp(selected.Text,'Spikes/Click')
                hold(app.UIAxes2,'off')
                cmap = colormap(jet(length(app.ICI_list)));
                
                for f = 2:2:length(app.ICI_list)
                    plot(app.UIAxes2,app.spikes_per_click{f}.xaxis,app.spikes_per_click{f}.brut,'color',cmap(f,:),'linewidth',1.7)%,'DisplayName',[num2str(ceil(1000/app.ICI_list(n))) 'Hz'])
                    hold(app.UIAxes2,'on');
                    
                end
                app.UIAxes2.XLim = [500 1100];
                hold(app.UIAxes2,'off')
                
            elseif strcmp(selected.Text,'Vector_Strength')
                %% Vector Strength
                
                plot(app.UIAxes2,app.ICI_list,app.vector)
                app.UIAxes2.YLabel.String = 'Vector Strength';
                app.UIAxes2.XLabel.String = 'IPI (ms)';
                app.UIAxes2.Title.String = 'Vector Strength';
                %             set(app.UIAxes2,'Visible', 'off');
                %             set(app.UIAxes2,'HandleVisibility', 'off');
                
            elseif strcmp(selected.Text,'Rate/Stimuli')
                hold(app.UIAxes2_2,'off')
                errorbar(app.UIAxes2_2,app.ICI_list,app.all_mean_rate_stim,app.all_std_rate_stim)
                app.UIAxes2_2.XLabel.String = 'IPI (ms)';
                app.UIAxes2_2.YLabel.String = 'rate (spikes/s)';
                app.UIAxes2_2.Title.String = 'Rate/Stimuli';
                app.UIAxes2_2.XLim = [0 max(app.ICI_list)+5];
                legend(app.UIAxes2_2,'off')
                
            elseif strcmp(selected.Text,'Fanofactor')
                hold(app.UIAxes2,'off')
                plot(app.UIAxes2,app.ICI_list,app.Fanofactor)
                app.UIAxes2.YLabel.String = 'Fanofactor';
                app.UIAxes2.XLabel.String = 'IPI (ms)';
                app.UIAxes2.Title.String = 'Fanofactor';
                hold(app.UIAxes2_2,'on')
                %                 line(app.UIAxes2,[0 app.ICI_list(end)],[1 1],'LineStyle','--');
            elseif  strcmp(selected.Text,'Variance')
                hold(app.UIAxes2,'off')
                plot(app.UIAxes2,app.ICI_list,app.var_isi); %app.meanOFstd) %app.all_std_rate_stim)
                app.UIAxes2.YLabel.String = 'Variance';
                app.UIAxes2.XLabel.String = 'IPI (ms)';
                app.UIAxes2.Title.String = 'Variance';
            end
            
        end

        % Value changed function: StimuliSetSwitch
        function StimuliSetSwitchValueChanged(app, event)
            state = app.StimuliSetSwitch.Value;
            load([ app.animal '_list3'])
            if strcmp(state,'20')
                app.indexes3 = find([UnitInfo.Info.Stimuli_Nb] == 20);
            else
                app.indexes3 = find([UnitInfo.Info.Stimuli_Nb] == 12);
            end
            if isempty(app.indexes1) && isempty(app.indexes2)
                app.indexes = app.indexes3;
            elseif isempty(app.indexes1)
                app.indexes = intersect(app.indexes2, app.indexes3);
            elseif isempty(app.indexes2)
                app.indexes = intersect(app.indexes1, app.indexes3);
            else
                app.indexes = intersect(app.indexes1, intersect(app.indexes2, app.indexes3));
            end
            Trans = {};
            for ind = 1:length(app.indexes)
                Trans{ind} = UnitInfo.List{app.indexes(ind)};
            end
            if ~isempty(Trans)
                app.ListBox2.Items = Trans;
            else
                app.ListBox2.Items = {};
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.ListBox2Label = uilabel(app.UIFigure);
            app.ListBox2Label.Text = 'List Box2';
            app.ListBox2 = uilistbox(app.UIFigure);
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @ListBox2ValueChanged, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'RasterPlot')
            xlabel(app.UIAxes, 'time(ms)')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.PlotsButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotsButtonGroupSelectionChanged, true);
            app.PlotsButtonGroup.Title = 'Plots';
            app.PSTHButton = uitogglebutton(app.PlotsButtonGroup);
            app.PSTHButton.Text = 'PSTH';
            app.PSTHButton.Value = true;
            app.Vector_StrengthButton = uitogglebutton(app.PlotsButtonGroup);
            app.Vector_StrengthButton.Text = 'Vector_Strength';
            app.RateStimuliButton = uitogglebutton(app.PlotsButtonGroup);
            app.RateStimuliButton.Text = 'Rate/Stimuli';
            app.FanofactorButton = uitogglebutton(app.PlotsButtonGroup);
            app.FanofactorButton.Text = 'Fanofactor';
            app.VarianceButton = uitogglebutton(app.PlotsButtonGroup);
            app.VarianceButton.Text = 'Variance';
            app.SpikesClickButton = uitogglebutton(app.PlotsButtonGroup);
            app.SpikesClickButton.Text = 'Spikes/Click';
            app.RunSelection = uibutton(app.UIFigure, 'state');
            app.RunSelection.Text = 'RUN';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.MonotonicKnobLabel = uilabel(app.UIFigure);
            app.MonotonicKnobLabel.Text = 'Monotonic';
            app.MonotonicKnob = uiknob(app.UIFigure, 'discrete');
            app.MonotonicKnob.Items = {'None', 'Positive', 'Negative'};
            app.MonotonicKnob.ValueChangedFcn = createCallbackFcn(app, @MonotonicKnobValueChanged, true);
            app.MonotonicKnob.Value = 'None';
            app.SyncNSyncSwitchLabel = uilabel(app.UIFigure);
            app.SyncNSyncSwitchLabel.Text = 'Sync/NSync';
            app.SyncNSyncSwitch = uiswitch(app.UIFigure, 'toggle');
            app.SyncNSyncSwitch.Items = {'Sync', 'NSync'};
            app.SyncNSyncSwitch.ValueChangedFcn = createCallbackFcn(app, @SyncNSyncSwitchValueChanged, true);
            app.SyncNSyncSwitch.Value = 'Sync';
            app.StimuliSetSwitchLabel = uilabel(app.UIFigure);
            app.StimuliSetSwitchLabel.Text = 'Stimuli Set';
            app.StimuliSetSwitch = uiswitch(app.UIFigure, 'toggle');
            app.StimuliSetSwitch.Items = {'20', '12'};
            app.StimuliSetSwitch.ValueChangedFcn = createCallbackFcn(app, @StimuliSetSwitchValueChanged, true);
            app.StimuliSetSwitch.Value = '20';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Data_filter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end